classdef register_2p_to_wf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        LoadfilesTab                matlab.ui.container.Tab
        imagesDBpathEditFieldLabel  matlab.ui.control.Label
        imagesDBpathEditField       matlab.ui.control.EditField
        LoadimagesDBButton          matlab.ui.control.Button
        tformDBpathEditFieldLabel   matlab.ui.control.Label
        tformDBpathEditField        matlab.ui.control.EditField
        LoadtformDBButton           matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        SavedataEditFieldLabel      matlab.ui.control.Label
        SavedataEditField           matlab.ui.control.EditField
        SaveButton                  matlab.ui.control.Button
        RegisterfovTab              matlab.ui.container.Tab
        WFimageDropDownLabel        matlab.ui.control.Label
        WFimageDropDown             matlab.ui.control.DropDown
        FOVimageDropDownLabel       matlab.ui.control.Label
        FOVimageDropDown            matlab.ui.control.DropDown
        TformDropDownLabel          matlab.ui.control.Label
        TformDropDown               matlab.ui.control.DropDown
        ButtonGroup                 matlab.ui.container.ButtonGroup
        OffButton                   matlab.ui.control.ToggleButton
        ApplytformButton            matlab.ui.control.ToggleButton
        ApplytformallButton         matlab.ui.control.ToggleButton
        RegionDropDownLabel         matlab.ui.control.Label
        RegionDropDown              matlab.ui.control.DropDown
        Panel                       matlab.ui.container.Panel
        PointsonButton              matlab.ui.control.StateButton
        UITablePoints               matlab.ui.control.Table
        AddpointButton              matlab.ui.control.Button
        RemovepointButton           matlab.ui.control.Button
        ClonetformButton            matlab.ui.control.Button
        EraseTformButton            matlab.ui.control.Button
        UpdatetformButton           matlab.ui.control.Button
        CreatetformButton           matlab.ui.control.Button
        FOV_clim                    matlab.ui.container.Panel
        CLimminEditFieldLabel       matlab.ui.control.Label
        CLimminEditField            matlab.ui.control.NumericEditField
        CLimmaxEditFieldLabel       matlab.ui.control.Label
        CLimmaxEditField            matlab.ui.control.NumericEditField
        ResetButton                 matlab.ui.control.Button
        WF_axes                     matlab.ui.control.UIAxes
        FOV_axes                    matlab.ui.control.UIAxes
        AlignWFTab                  matlab.ui.container.Tab
        UITableMapping              matlab.ui.control.Table
        WFimageDropDown_2Label      matlab.ui.control.Label
        WFimageDropDownMapping      matlab.ui.control.DropDown
        AddregionButton             matlab.ui.control.Button
        AddregionEditField          matlab.ui.control.EditField
        RemoveregionButton          matlab.ui.control.Button
        ContourButton               matlab.ui.control.StateButton
        WF_axes_mapping             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ops; % Description
        data;
        data_all = [];
        fov_axes;
        wf_axes;
        wf_axes_map;
        pt2p;
        ptwf;
        UITablePointsSelection;
        current_mapping_plot = 1;
        mapping_regions;
        map_pt_colors = {'m', 'g', 'b', 'r', 'c', 'y', 'k'};
        map_pt;
        map_current_tab_selection;
        current_fov_is_surface = 1;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ops = GUI_params();
            f_reg_initialize(app);
        end

        % Callback function
        function LoadWFButtonPushed(app, event)
            f_reg_load_WF_button_pushed(app);
        end

        % Callback function
        function LoadFOVButtonPushed(app, event)
            f_reg_load_FOV_button_pushed(app);
        end

        % Button pushed function: LoadimagesDBButton
        function LoadimagesDBButtonPushed(app, event)
            f_reg_load_db_images(app);
        end

        % Button pushed function: LoadtformDBButton
        function LoadtformDBButtonPushed(app, event)
            f_reg_load_db_tform(app);
        end

        % Value changed function: WFimageDropDown
        function WFimageDropDownValueChanged(app, event)
            f_reg_update_dropdown(app);
            f_reg_update_plot_wf(app);
            f_reg_update_plot_fov(app);
            f_reg_point_on(app);
        end

        % Value changed function: FOVimageDropDown
        function FOVimageDropDownValueChanged(app, event)
            f_reg_update_dropdown(app);
            f_reg_update_plot_wf(app);
            f_reg_update_plot_fov(app);  
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            f_reg_update_dropdown(app);
            f_reg_update_plot_wf(app);
            f_reg_update_plot_fov(app);
            f_reg_point_on(app);
        end

        % Value changed function: TformDropDown
        function TformDropDownValueChanged(app, event)
            f_reg_update_ctform(app);
            f_reg_update_plot_wf(app);
            f_reg_points_remove(app);
            f_reg_points_add(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            f_reg_update_plot_wf(app);
            f_reg_point_on(app);
        end

        % Value changed function: PointsonButton
        function PointsonButtonValueChanged(app, event)
            f_reg_point_on(app);
        end

        % Button pushed function: ClonetformButton
        function ClonetformButtonPushed(app, event)
            f_reg_tform_clone(app);
        end

        % Button pushed function: EraseTformButton
        function EraseTformButtonPushed(app, event)
            f_reg_tform_erase(app);
        end

        % Button pushed function: AddpointButton
        function AddpointButtonPushed(app, event)
            f_reg_point_add_pt(app);
        end

        % Button pushed function: RemovepointButton
        function RemovepointButtonPushed(app, event)
            f_reg_point_remove_pt(app);
        end

        % Cell selection callback: UITablePoints
        function UITablePointsCellSelection(app, event)
            app.UITablePointsSelection = event.Indices;
        end

        % Button pushed function: UpdatetformButton
        function UpdatetformButtonPushed(app, event)
            f_reg_update_tform(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            f_reg_reset_clim(app);
        end

        % Value changed function: CLimminEditField
        function CLimminEditFieldValueChanged(app, event)
            f_reg_set_clim(app);
        end

        % Value changed function: CLimmaxEditField
        function CLimmaxEditFieldValueChanged(app, event)
            f_reg_set_clim(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            f_reg_save_data(app);
        end

        % Button pushed function: CreatetformButton
        function CreatetformButtonPushed(app, event)
            f_reg_create_tfrom(app);
        end

        % Value changed function: WFimageDropDownMapping
        function WFimageDropDownMappingValueChanged(app, event)
            f_reg_align_point_remove(app);
            f_reg_align_update_table(app);
            f_reg_align_update_plot_wf(app);
            f_reg_align_point_add(app);
        end

        % Cell selection callback: UITableMapping
        function UITableMappingCellSelection(app, event)
            f_reg_align_point_save(app);
            f_reg_align_point_remove(app);
            f_reg_align_update_current_mapping(app, event);
            f_reg_align_update_plot_wf(app);
            f_reg_align_point_add(app);
        end

        % Button pushed function: AddregionButton
        function AddregionButtonPushed(app, event)
            f_reg_align_region_add(app);
            f_reg_align_update_table(app);
            f_reg_align_point_save(app);
            f_reg_align_point_remove(app);
            f_reg_align_point_add(app);
        end

        % Button pushed function: RemoveregionButton
        function RemoveregionButtonPushed(app, event)
            f_reg_align_region_remove(app);
        end

        % Cell edit callback: UITableMapping
        function UITableMappingCellEdit(app, event)
            f_reg_align_table_val_changed(app, event);
            f_reg_align_point_save(app);
            f_reg_align_point_remove(app);
            f_reg_align_point_add(app);
        end

        % Value changed function: ContourButton
        function ContourButtonValueChanged(app, event)
            f_reg_contour_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadfilesTab = uitab(app.TabGroup);
            app.LoadfilesTab.Tooltip = {''};
            app.LoadfilesTab.Title = 'Load files';
            app.imagesDBpathEditFieldLabel = uilabel(app.LoadfilesTab);
            app.imagesDBpathEditFieldLabel.Text = 'images DB path';
            app.imagesDBpathEditField = uieditfield(app.LoadfilesTab, 'text');
            app.LoadimagesDBButton = uibutton(app.LoadfilesTab, 'push');
            app.LoadimagesDBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesDBButtonPushed, true);
            app.LoadimagesDBButton.Tooltip = {'Input mat file with:'; 'wf_data table with columns: {''mouse_tag'' ''wf_fname'' ''wf_im''} '; 'fov_data table with columns {''mouse_tag'' ''wf_fname'' ''area'' ''fov_fname''  ''fov_im''}'};
            app.LoadimagesDBButton.Text = 'Load images DB';
            app.tformDBpathEditFieldLabel = uilabel(app.LoadfilesTab);
            app.tformDBpathEditFieldLabel.Text = 'tform DB path';
            app.tformDBpathEditField = uieditfield(app.LoadfilesTab, 'text');
            app.LoadtformDBButton = uibutton(app.LoadfilesTab, 'push');
            app.LoadtformDBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtformDBButtonPushed, true);
            app.LoadtformDBButton.Text = 'Load tform DB';
            app.UITable = uitable(app.LoadfilesTab);
            app.UITable.ColumnName = {'mouse_tag'; 'area'; 'wf_fname'; 'fov_fname'};
            app.UITable.RowName = {};
            app.SavedataEditFieldLabel = uilabel(app.LoadfilesTab);
            app.SavedataEditFieldLabel.Text = 'Save data';
            app.SavedataEditField = uieditfield(app.LoadfilesTab, 'text');
            app.SaveButton = uibutton(app.LoadfilesTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RegisterfovTab = uitab(app.TabGroup);
            app.RegisterfovTab.Title = 'Register fov';
            app.WFimageDropDownLabel = uilabel(app.RegisterfovTab);
            app.WFimageDropDownLabel.Text = 'WF image';
            app.WFimageDropDown = uidropdown(app.RegisterfovTab);
            app.WFimageDropDown.Items = {};
            app.WFimageDropDown.ValueChangedFcn = createCallbackFcn(app, @WFimageDropDownValueChanged, true);
            app.WFimageDropDown.Value = {};
            app.FOVimageDropDownLabel = uilabel(app.RegisterfovTab);
            app.FOVimageDropDownLabel.Text = 'FOV image';
            app.FOVimageDropDown = uidropdown(app.RegisterfovTab);
            app.FOVimageDropDown.Items = {};
            app.FOVimageDropDown.ValueChangedFcn = createCallbackFcn(app, @FOVimageDropDownValueChanged, true);
            app.FOVimageDropDown.Value = {};
            app.TformDropDownLabel = uilabel(app.RegisterfovTab);
            app.TformDropDownLabel.Text = 'Tform';
            app.TformDropDown = uidropdown(app.RegisterfovTab);
            app.TformDropDown.Items = {};
            app.TformDropDown.ValueChangedFcn = createCallbackFcn(app, @TformDropDownValueChanged, true);
            app.TformDropDown.Value = {};
            app.ButtonGroup = uibuttongroup(app.RegisterfovTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.OffButton = uitogglebutton(app.ButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.ApplytformButton = uitogglebutton(app.ButtonGroup);
            app.ApplytformButton.Text = 'Apply tform';
            app.ApplytformallButton = uitogglebutton(app.ButtonGroup);
            app.ApplytformallButton.Text = 'Apply tform all';
            app.RegionDropDownLabel = uilabel(app.RegisterfovTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.RegisterfovTab);
            app.RegionDropDown.Items = {};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = {};
            app.Panel = uipanel(app.RegisterfovTab);
            app.Panel.Title = 'Panel';
            app.PointsonButton = uibutton(app.Panel, 'state');
            app.PointsonButton.ValueChangedFcn = createCallbackFcn(app, @PointsonButtonValueChanged, true);
            app.PointsonButton.Text = 'Points on';
            app.UITablePoints = uitable(app.Panel);
            app.UITablePoints.ColumnName = {'Points'};
            app.UITablePoints.RowName = {};
            app.UITablePoints.CellSelectionCallback = createCallbackFcn(app, @UITablePointsCellSelection, true);
            app.AddpointButton = uibutton(app.Panel, 'push');
            app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointButtonPushed, true);
            app.AddpointButton.Text = 'Add point';
            app.RemovepointButton = uibutton(app.Panel, 'push');
            app.RemovepointButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepointButtonPushed, true);
            app.RemovepointButton.Text = 'Remove point';
            app.ClonetformButton = uibutton(app.Panel, 'push');
            app.ClonetformButton.ButtonPushedFcn = createCallbackFcn(app, @ClonetformButtonPushed, true);
            app.ClonetformButton.Text = 'Clone tform';
            app.EraseTformButton = uibutton(app.Panel, 'push');
            app.EraseTformButton.ButtonPushedFcn = createCallbackFcn(app, @EraseTformButtonPushed, true);
            app.EraseTformButton.Text = 'Erase Tform';
            app.UpdatetformButton = uibutton(app.Panel, 'push');
            app.UpdatetformButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetformButtonPushed, true);
            app.UpdatetformButton.Text = 'Update tform';
            app.CreatetformButton = uibutton(app.Panel, 'push');
            app.CreatetformButton.ButtonPushedFcn = createCallbackFcn(app, @CreatetformButtonPushed, true);
            app.CreatetformButton.Text = 'Create tform';
            app.FOV_clim = uipanel(app.RegisterfovTab);
            app.CLimminEditFieldLabel = uilabel(app.FOV_clim);
            app.CLimminEditFieldLabel.Text = 'CLim min ';
            app.CLimminEditField = uieditfield(app.FOV_clim, 'numeric');
            app.CLimminEditField.Limits = [0 1];
            app.CLimminEditField.ValueChangedFcn = createCallbackFcn(app, @CLimminEditFieldValueChanged, true);
            app.CLimmaxEditFieldLabel = uilabel(app.FOV_clim);
            app.CLimmaxEditFieldLabel.Text = 'CLim max';
            app.CLimmaxEditField = uieditfield(app.FOV_clim, 'numeric');
            app.CLimmaxEditField.Limits = [0 Inf];
            app.CLimmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CLimmaxEditFieldValueChanged, true);
            app.CLimmaxEditField.Value = 1;
            app.ResetButton = uibutton(app.FOV_clim, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.WF_axes = uiaxes(app.RegisterfovTab);
            title(app.WF_axes, 'Widefield')
            app.FOV_axes = uiaxes(app.RegisterfovTab);
            title(app.FOV_axes, 'FOV')
            app.AlignWFTab = uitab(app.TabGroup);
            app.AlignWFTab.Title = 'Align WF';
            app.UITableMapping = uitable(app.AlignWFTab);
            app.UITableMapping.ColumnName = {'Area'};
            app.UITableMapping.RowName = {};
            app.UITableMapping.ColumnEditable = true;
            app.UITableMapping.CellEditCallback = createCallbackFcn(app, @UITableMappingCellEdit, true);
            app.UITableMapping.CellSelectionCallback = createCallbackFcn(app, @UITableMappingCellSelection, true);
            app.WFimageDropDown_2Label = uilabel(app.AlignWFTab);
            app.WFimageDropDown_2Label.Text = 'WF image';
            app.WFimageDropDownMapping = uidropdown(app.AlignWFTab);
            app.WFimageDropDownMapping.Items = {};
            app.WFimageDropDownMapping.ValueChangedFcn = createCallbackFcn(app, @WFimageDropDownMappingValueChanged, true);
            app.WFimageDropDownMapping.Value = {};
            app.AddregionButton = uibutton(app.AlignWFTab, 'push');
            app.AddregionButton.ButtonPushedFcn = createCallbackFcn(app, @AddregionButtonPushed, true);
            app.AddregionButton.Text = 'Add region';
            app.AddregionEditField = uieditfield(app.AlignWFTab, 'text');
            app.RemoveregionButton = uibutton(app.AlignWFTab, 'push');
            app.RemoveregionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveregionButtonPushed, true);
            app.RemoveregionButton.Text = 'Remove region';
            app.ContourButton = uibutton(app.AlignWFTab, 'state');
            app.ContourButton.ValueChangedFcn = createCallbackFcn(app, @ContourButtonValueChanged, true);
            app.ContourButton.Text = 'Contour';
            app.WF_axes_mapping = uiaxes(app.AlignWFTab);
            title(app.WF_axes_mapping, 'Tonotopy alignment')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = register_2p_to_wf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end